classdef Gas_sim < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GasSimulatorUIFigure            matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        SimulationSetupTab              matlab.ui.container.Tab
        ParticlespacingfactorEditField  matlab.ui.control.NumericEditField
        ParticlespacingfactorEditFieldLabel  matlab.ui.control.Label
        GasflowCheckBox                 matlab.ui.control.CheckBox
        StatusEditField                 matlab.ui.control.EditField
        StatusEditFieldLabel            matlab.ui.control.Label
        StartsimulationButton           matlab.ui.control.Button
        OpeninteractiveinputwindowButton  matlab.ui.control.Button
        AnimationandgraphsettingsPanel  matlab.ui.container.Panel
        EnableproberegionCheckBox       matlab.ui.control.CheckBox
        MovingaveragewindowEditField    matlab.ui.control.NumericEditField
        MovingaveragewindowEditFieldLabel  matlab.ui.control.Label
        AnimationspeedEditField         matlab.ui.control.NumericEditField
        AnimationspeedEditFieldLabel    matlab.ui.control.Label
        CreateanimationvideoCheckBox    matlab.ui.control.CheckBox
        SimulationenvironmentsettingsPanel  matlab.ui.container.Panel
        kcalmolLabel                    matlab.ui.control.Label
        ms2Label                        matlab.ui.control.Label
        WLabel                          matlab.ui.control.Label
        fsLabel_4                       matlab.ui.control.Label
        fsLabel_3                       matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        SelfreactingredgasCheckBox      matlab.ui.control.CheckBox
        SelfreactingbluegasCheckBox     matlab.ui.control.CheckBox
        ThresholdcollisionenergyEditField  matlab.ui.control.NumericEditField
        ThresholdcollisionenergyEditFieldLabel  matlab.ui.control.Label
        ReactivegasCheckBox             matlab.ui.control.CheckBox
        AcclelerationduetogravityEditField  matlab.ui.control.NumericEditField
        AcclelerationduetogravityEditFieldLabel  matlab.ui.control.Label
        IncludegravityCheckBox          matlab.ui.control.CheckBox
        HeatinputEditField              matlab.ui.control.NumericEditField
        HeatinputEditFieldLabel         matlab.ui.control.Label
        TimestepEditField               matlab.ui.control.NumericEditField
        TimestepEditFieldLabel          matlab.ui.control.Label
        TotalsimulationtimeEditField    matlab.ui.control.NumericEditField
        TotalsimulationtimeEditFieldLabel  matlab.ui.control.Label
        BoxheightEditField              matlab.ui.control.NumericEditField
        BoxheightEditFieldLabel         matlab.ui.control.Label
        BoxwidthEditField               matlab.ui.control.NumericEditField
        BoxwidthEditFieldLabel          matlab.ui.control.Label
        IncludeheattransferfrombottomwallCheckBox  matlab.ui.control.CheckBox
        IncludeinnerpartitionsCheckBox  matlab.ui.control.CheckBox
        RedgasparametersPanel           matlab.ui.container.Panel
        fsLabel_2                       matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        gmmolLabel_2                    matlab.ui.control.Label
        InitialparticlespeedEditField_2  matlab.ui.control.NumericEditField
        InitialparticlespeedEditField_2Label  matlab.ui.control.Label
        NoofparticlesEditField_2        matlab.ui.control.NumericEditField
        NoofparticlesEditField_2Label   matlab.ui.control.Label
        ParticlediameterEditField_2     matlab.ui.control.NumericEditField
        ParticlediameterEditField_2Label  matlab.ui.control.Label
        ParticlemassEditField_2         matlab.ui.control.NumericEditField
        ParticlemassEditField_2Label    matlab.ui.control.Label
        BluegasparametersPanel          matlab.ui.container.Panel
        fsLabel                         matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        gmmolLabel                      matlab.ui.control.Label
        InitialparticlespeedEditField   matlab.ui.control.NumericEditField
        InitialparticlespeedEditFieldLabel  matlab.ui.control.Label
        NoofparticlesEditField          matlab.ui.control.NumericEditField
        NoofparticlesEditFieldLabel     matlab.ui.control.Label
        ParticlediameterEditField       matlab.ui.control.NumericEditField
        ParticlediameterEditFieldLabel  matlab.ui.control.Label
        ParticlemassEditField           matlab.ui.control.NumericEditField
        ParticlemassEditFieldLabel      matlab.ui.control.Label
        GascomponentsDropDown           matlab.ui.control.DropDown
        GascomponentsDropDownLabel      matlab.ui.control.Label
        PlotsofThermophysicalQuantitiesTab  matlab.ui.container.Tab
        SelectagraphButtonGroup         matlab.ui.container.ButtonGroup
        RegionconcentrationButton       matlab.ui.control.RadioButton
        RegioninternalenergyButton      matlab.ui.control.RadioButton
        SpeeddistributionButton         matlab.ui.control.RadioButton
        TopwallpressureButton           matlab.ui.control.RadioButton
        UIAxes                          matlab.ui.control.UIAxes
        PlotofChemicalSpeciesTab        matlab.ui.container.Tab
        UIAxes2                         matlab.ui.control.UIAxes
        HelpTab                         matlab.ui.container.Tab
        MiscelleneousInfoTextArea       matlab.ui.control.TextArea
        MiscelleneousInfoTextAreaLabel  matlab.ui.control.Label
        HowtouseTextArea                matlab.ui.control.TextArea
        HowtouseTextAreaLabel           matlab.ui.control.Label
    end

    % Simulation user input properties
    properties (Access = public)
        twoParticleMix = 0;
        innerPartitions = 0;
        heatTransferBottom = 0;
        reactiveGas = 0;
        gasFlow = 0;
        gravity = 0;
        makeVideo = 0;
        selfReact_A = 1;
        selfReact_B = 1;
        probeRegion = 0;

        massPart_A = 16;% mass of particles 
        nPart_A = 100;% no. of particles
        size_A = 3.04;% particle diameter
        speed_A = 0.0056;% initial speed
        massPart_B = 12;
        nPart_B = 100;
        size_B = 3.4;
        speed_B = 0.0056;

        g = 9.8; % acceleration due to gravity (delta_V/timeStep)
        heatIn = 5e-8;% in units of energy/time
        criticalEnergy = 1.5; % Minimum total K.E. of two atoms along line-of-centre for reaction to happen
        partExcess = 20; % Excess particle outside box at inlet
        partSpace = 2; % particle spacing factor (space = factor*size)
        box_xmax = 100;% max box width
        box_ymax = 100;% max box breadth
        timeTotal = 100000;
        timeStep = 100;% dicretized time
        simSpeedUp = 1;% simulation speed up [>= 1, integer]
        movingAverageWindow = 100;

        initLoc_A
        initLoc_B
        wallPts
        noOfWalls
        probeRect
        probeRectCoord_X
        probeRectCoord_Y
        pressure
        speedArray
        concentration
        energy
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: GascomponentsDropDown
        function GascomponentsDropDownValueChanged(app, event)
            value = app.GascomponentsDropDown.Value;
            if value == "Single gas"
                app.BluegasparametersPanel.Visible = 'on';
                app.RedgasparametersPanel.Visible = 'off';
                app.twoParticleMix = 0;
            else
                app.BluegasparametersPanel.Visible = 'on';
                app.RedgasparametersPanel.Visible = 'on';
                app.twoParticleMix = 1;
            end
        end

        % Value changed function: IncludeheattransferfrombottomwallCheckBox
        function IncludeheattransferfrombottomwallCheckBoxValueChanged(app, event)
            value = app.IncludeheattransferfrombottomwallCheckBox.Value;
            if value == 1
                app.HeatinputEditField.Visible = "on";
                app.HeatinputEditFieldLabel.Visible = "on";
                app.WLabel.Visible = "on";
                app.heatTransferBottom = value;
            else
                app.HeatinputEditField.Visible = "off";
                app.HeatinputEditFieldLabel.Visible = "off";
                app.WLabel.Visible = "off";
                app.heatTransferBottom = value;
            end
        end

        % Value changed function: ParticlemassEditField
        function ParticlemassEditFieldValueChanged(app, event)
            value = app.ParticlemassEditField.Value;
            app.massPart_A = value;
        end

        % Value changed function: ParticlediameterEditField
        function ParticlediameterEditFieldValueChanged(app, event)
            value = app.ParticlediameterEditField.Value;
            app.size_A = value;
        end

        % Value changed function: NoofparticlesEditField
        function NoofparticlesEditFieldValueChanged(app, event)
            value = app.NoofparticlesEditField.Value;
            app.nPart_A = value;
        end

        % Value changed function: InitialparticlespeedEditField
        function InitialparticlespeedEditFieldValueChanged(app, event)
            value = app.InitialparticlespeedEditField.Value;
            app.speed_A = value;
        end

        % Value changed function: ParticlemassEditField_2
        function ParticlemassEditField_2ValueChanged(app, event)
            value = app.ParticlemassEditField_2.Value;
            app.massPart_B = value;
        end

        % Value changed function: ParticlediameterEditField_2
        function ParticlediameterEditField_2ValueChanged(app, event)
            value = app.ParticlediameterEditField_2.Value;
            app.size_B = value;
        end

        % Value changed function: NoofparticlesEditField_2
        function NoofparticlesEditField_2ValueChanged(app, event)
            value = app.NoofparticlesEditField_2.Value;
            app.nPart_B = value;
        end

        % Value changed function: InitialparticlespeedEditField_2
        function InitialparticlespeedEditField_2ValueChanged(app, event)
            value = app.InitialparticlespeedEditField_2.Value;
            app.speed_B = value;
        end

        % Value changed function: BoxwidthEditField
        function BoxwidthEditFieldValueChanged(app, event)
            value = app.BoxwidthEditField.Value;
            app.box_xmax = value;
        end

        % Value changed function: BoxheightEditField
        function BoxheightEditFieldValueChanged(app, event)
            value = app.BoxheightEditField.Value;
            app.box_ymax = value;
        end

        % Value changed function: TotalsimulationtimeEditField
        function TotalsimulationtimeEditFieldValueChanged(app, event)
            value = app.TotalsimulationtimeEditField.Value;
            app.timeTotal = value;
        end

        % Value changed function: TimestepEditField
        function TimestepEditFieldValueChanged(app, event)
            value = app.TimestepEditField.Value;
            app.timeStep = value;
        end

        % Value changed function: HeatinputEditField
        function HeatinputEditFieldValueChanged(app, event)
            value = app.HeatinputEditField.Value;
            app.heatIn = value;
        end

        % Value changed function: AnimationspeedEditField
        function AnimationspeedEditFieldValueChanged(app, event)
            value = app.AnimationspeedEditField.Value;
            app.simSpeedUp = value;
        end

        % Value changed function: MovingaveragewindowEditField
        function MovingaveragewindowEditFieldValueChanged(app, event)
            value = app.MovingaveragewindowEditField.Value;
            app.movingAverageWindow = value;
        end

        % Value changed function: IncludeinnerpartitionsCheckBox
        function IncludeinnerpartitionsCheckBoxValueChanged(app, event)
            value = app.IncludeinnerpartitionsCheckBox.Value;
            app.innerPartitions = value;
        end

        % Value changed function: CreateanimationvideoCheckBox
        function CreateanimationvideoCheckBoxValueChanged(app, event)
            value = app.CreateanimationvideoCheckBox.Value;
            app.makeVideo = value;
        end

        % Button pushed function: OpeninteractiveinputwindowButton
        function OpeninteractiveinputwindowButtonPushed(app, event)
            close all;
            
            if app.gasFlow == 0
                % Initial particle region
                fig = figure();
                fig.WindowState = 'maximized';
                rectangle('Position',[-app.box_xmax/2,-app.box_ymax/2,app.box_xmax,app.box_ymax],'LineWidth',1.5);
                axis equal;
                axis([-app.box_xmax/2 - 10, app.box_xmax/2 + 10, -app.box_ymax/2 - 10, app.box_ymax/2 + 10]);
                xticks(-app.box_xmax/2:10:app.box_xmax/2);
                yticks(-app.box_ymax/2:10:app.box_ymax/2);
                grid on;
                title('Select initial region of blue (and red) particles');
                rect_A = drawrectangle(Color="blue");
                if app.twoParticleMix == 1
                    rect_B = drawrectangle(Color="red");
                end
                pause;
                app.initLoc_A = rect_A.Position;
                if app.twoParticleMix == 1
                    app.initLoc_B = rect_B.Position;
                else
                    app.initLoc_B = [0 0 0 0];
                end
    
                % Wall locations
                if app.innerPartitions == 1
                    title('Select end points of walls');
                    ctr=1;
                    while 1
                        wall = drawline(Color="black");
                        app.wallPts(1:2,1:2,ctr) = wall.Position;
                        pause;
                        c = get(gcf,'CurrentCharacter');
                        if c == 'x'
                            break;
                        end
                        ctr=ctr+1;
                    end
                    app.noOfWalls = ctr;
                end
                
                % Probe region
                if app.probeRegion == 1
                    title('Select probe region for concentration and internal energy')
                    rect = drawrectangle(Color="green");
                    pause;
                    app.probeRect = rect.Position;
                    app.probeRectCoord_X = [app.probeRect(1) app.probeRect(1) (app.probeRect(1)+app.probeRect(3)) (app.probeRect(1)+app.probeRect(3))];
                    app.probeRectCoord_Y = [app.probeRect(2) (app.probeRect(2)+app.probeRect(4)) (app.probeRect(2)+app.probeRect(4)) app.probeRect(2)];
                end
            else
                % Wall locations
                fig = figure();
                fig.WindowState = 'maximized';
                rectangle('Position',[-app.box_xmax/2,-app.box_ymax/2,app.box_xmax,app.box_ymax],'LineWidth',1.5);
                axis equal;
                axis([-app.box_xmax/2 - 10, app.box_xmax/2 + 10, -app.box_ymax/2 - 10, app.box_ymax/2 + 10]);
                xticks(-app.box_xmax/2:10:app.box_xmax/2);
                yticks(-app.box_ymax/2:10:app.box_ymax/2);
                grid on;
                if app.innerPartitions == 1
                    title('Select end points of walls');
                    ctr=1;
                    while 1
                        wall = drawline(Color="black");
                        app.wallPts(1:2,1:2,ctr) = wall.Position;
                        pause;
                        c = get(gcf,'CurrentCharacter');
                        if c == 'x'
                            break;
                        end
                        ctr=ctr+1;
                    end
                    app.noOfWalls = ctr;
                end
                
                % Probe region
                if app.probeRegion == 1
                    title('Select probe region for concentration and internal energy')
                    rect = drawrectangle(Color="green");
                    pause;
                    app.probeRect = rect.Position;
                    app.probeRectCoord_X = [app.probeRect(1) app.probeRect(1) (app.probeRect(1)+app.probeRect(3)) (app.probeRect(1)+app.probeRect(3))];
                    app.probeRectCoord_Y = [app.probeRect(2) (app.probeRect(2)+app.probeRect(4)) (app.probeRect(2)+app.probeRect(4)) app.probeRect(2)];
                end
            end

            close;

            app.StatusEditField.Value = "";
        end

        % Button pushed function: StartsimulationButton
        function StartsimulationButtonPushed(app, event)
            close all;

            app.StatusEditField.Value = "In progress";
            pause(0.01);

            if app.gasFlow == 0
                %% Initialise particles' state
                
                if app.twoParticleMix == 0
                    app.nPart_B = 0;
                else
                    app.nPart_B = app.NoofparticlesEditField_2.Value;
                end
    
                itr = floor(app.timeTotal/app.timeStep);
                size = [app.size_A app.size_B];
                massPart = [app.massPart_A app.massPart_B];
                massPartArray = [app.massPart_A*ones(1,app.nPart_A) app.massPart_B*ones(1,app.nPart_B)];
                initX_A = app.initLoc_A(3)*rand(1,app.nPart_A) + app.initLoc_A(1); % initial X-coordinates
                initY_A = app.initLoc_A(4)*rand(1,app.nPart_A) + app.initLoc_A(2); % initial Y-coordinates
                initX_B = app.initLoc_B(3)*rand(1,app.nPart_B) + app.initLoc_B(1);
                initY_B = app.initLoc_B(4)*rand(1,app.nPart_B) + app.initLoc_B(2);
                initX = [initX_A initX_B];
                initY = [initY_A initY_B];
                initAngle = 360*rand(1,app.nPart_A+app.nPart_B) + 0; % initial angle w.r.t x-axis
                initSpeed = [app.speed_A*ones(1,app.nPart_A) app.speed_B*ones(1,app.nPart_B)];
                isTopWallCollision = zeros(itr,length(initX));
                if app.innerPartitions == 1
                    isCollisionWallEnd = zeros(app.nPart_A+app.nPart_B,2,app.noOfWalls); % wall end collision detection matrix
                    isCollisionWallEdge = zeros(app.noOfWalls,app.nPart_A+app.nPart_B); % wall edge collision detection matrix
                end
                isMolecule = zeros(1,app.nPart_A+app.nPart_B);
                molCtr = 0; % molecule counter
                speciesHistory = zeros(itr,5); % B, R, BB, RR, BR
                speciesHistory(:,1) = app.nPart_A;
                speciesHistory(:,2) = app.nPart_B;
                cellSize_x = app.box_xmax/floor(app.box_xmax/max(size)); % Hash grid cell size
                cellSize_y = app.box_ymax/floor(app.box_ymax/max(size));
                cellNos_x = floor(app.box_xmax/max(size)); % No. of cells
                cellNos_y = floor(app.box_ymax/max(size));
                cellSearchRange = app.reactiveGas + 1;
                
                % Making the wall box
                if app.innerPartitions == 1
                    wallNormalVect = zeros(app.noOfWalls,2); % unit wall normal vector
                    for w = 1:app.noOfWalls
                        wallNormalVect(w,:) = [-(app.wallPts(2,2,w)-app.wallPts(1,2,w)), app.wallPts(2,1,w)-app.wallPts(1,1,w)];
                        wallNormalVect(w,:) = wallNormalVect(w,:)/norm(wallNormalVect(w,:));
                    end
                    addVect_A = (size(1)/2)*wallNormalVect;
                    wallBox_A = zeros(4,2,app.noOfWalls);
                    if app.twoParticleMix == 1
                        addVect_B = (size(2)/2)*wallNormalVect;
                        wallBox_B = zeros(4,2,app.noOfWalls);
                    end
                    for w = 1:app.noOfWalls
                        wallBox_A(:,:,w) = [app.wallPts(1,:,w)+addVect_A(w,:); app.wallPts(1,:,w)-addVect_A(w,:); app.wallPts(2,:,w)-addVect_A(w,:); app.wallPts(2,:,w)+addVect_A(w,:)];
                        if app.twoParticleMix == 1
                            wallBox_B(:,:,w) = [app.wallPts(1,:,w)+addVect_B(w,:); app.wallPts(1,:,w)-addVect_B(w,:); app.wallPts(2,:,w)-addVect_B(w,:); app.wallPts(2,:,w)+addVect_B(w,:)];
                        end
                    end
                end
                
                %% Simulate particles
                
                X = zeros(itr,length(initX));
                Y = zeros(itr,length(initY));
                X(1,:) = initX;
                Y(1,:) = initY;
                
                velX = zeros(itr,length(initX));
                velY = zeros(itr,length(initY));
                velX(1,:) = initSpeed.*cosd(initAngle);
                velY(1,:) = initSpeed.*sind(initAngle);
                velX0 = velX(1,:);
                velY0 = velY(1,:);
                
                xmax = app.box_xmax/2 - [app.size_A/2*ones(1,app.nPart_A) app.size_B/2*ones(1,app.nPart_B)];
                xmin = -xmax;
                ymax = app.box_ymax/2 - [app.size_A/2*ones(1,app.nPart_A) app.size_B/2*ones(1,app.nPart_B)];
                ymin = -ymax;
                
                % calculate particles' coordinates for all times
                for i = 2:itr
                    % move particle
                    X(i,:) = X(i-1,:) + velX0*app.timeStep;
                    Y(i,:) = Y(i-1,:) + velY0*app.timeStep;
    
                    % collision with box wall
                    velX0 = velX0.*((((X(i,:) > xmax) | (X(i,:) < xmin)) - 0.5)/(-0.5));
                    velY0 = velY0.*((((Y(i,:) > ymax) | (Y(i,:) < ymin)) - 0.5)/(-0.5));
                    X(i,:) = X(i,:) - 2*((X(i,:) > xmax).*(X(i,:) - xmax));
                    Y(i,:) = Y(i,:) - 2*((Y(i,:) > ymax).*(Y(i,:) - ymax));
                    X(i,:) = X(i,:) + 2*((X(i,:) < xmin).*(xmin - X(i,:)));
                    Y(i,:) = Y(i,:) + 2*((Y(i,:) < ymin).*(ymin - Y(i,:)));
                    isTopWallCollision(i,:) = velY(i-1,:)./velY0 == -1 & velY0 < 0;
    
                    % add heat energy to particles collided with bottom wall
                    if app.heatTransferBottom == 1
                        isBottomWallCollision = velY(i-1,:)./velY0 == -1 & velY(i-1,:) < 0;
                        velY0 = isBottomWallCollision .* sqrt(velY0.^2 + 2*(app.heatIn*app.timeStep*10^-15)*(1000*(6.023*10^23)*(10^-10))./massPartArray) + ~isBottomWallCollision.*velY0;
                    end
                
                    % check inside wall edge collision
                    if app.innerPartitions == 1
                        for w = 1:app.noOfWalls
                            in = inpolygon(X(i,1:app.nPart_A),Y(i,1:app.nPart_A),wallBox_A(:,1,w),wallBox_A(:,2,w));
                            isCollisionWallEdge(w,1:app.nPart_A) = in;
                            if app.twoParticleMix == 1
                                in = inpolygon(X(i,app.nPart_A+1:end),Y(i,app.nPart_A+1:end),wallBox_B(:,1,w),wallBox_B(:,2,w));
                                isCollisionWallEdge(w,app.nPart_A+1:end) = in;
                            end
                        end
                    end
    
                    % reset grid
                    lookup = zeros(cellNos_x,cellNos_y);
                    lookupCtr = ones(cellNos_x,cellNos_y);
                
                    % assign particles to grid
                    for p = 1:(app.nPart_A+app.nPart_B)
                        cellX = ceil((app.box_xmax/2 + X(i,p))/cellSize_x); 
                        cellY = ceil((app.box_ymax/2 + Y(i,p))/cellSize_y);
                        lookup(cellX,cellY,lookupCtr(cellX,cellY)) = p;
                        lookupCtr(cellX,cellY) = lookupCtr(cellX,cellY) + 1;
                    end
                
                    % check neighbouring cells for each particle and register inter-particle and inside wall collision
                    for m = 1:(app.nPart_A+app.nPart_B)
                        if app.innerPartitions == 1
                            for w = 1:app.noOfWalls
                                distWallEnd1 = sqrt((app.wallPts(1,1,w) - X(i,m))^2 + (app.wallPts(1,2,w) - Y(i,m))^2);
                                distWallEnd2 = sqrt((app.wallPts(2,1,w) - X(i,m))^2 + (app.wallPts(2,2,w) - Y(i,m))^2);
                                isCollisionWallEnd(m,:,w) = [distWallEnd1 distWallEnd2] < (size((m>app.nPart_A)+1)/2)*ones(1,2);
                
                                % remove overlapping wall detection for end and edge regions
                                isCollisionWallEnd(m,1,w) = (~(isCollisionWallEnd(m,1,w) & isCollisionWallEdge(w,m)) & isCollisionWallEnd(m,1,w));
                                isCollisionWallEnd(m,2,w) = (~(isCollisionWallEnd(m,2,w) & isCollisionWallEdge(w,m)) & isCollisionWallEnd(m,2,w));
                
                                if isCollisionWallEnd(m,1,w) == 1
                                    slopeVect = [(X(i,m) - app.wallPts(1,1,w)) (Y(i,m) - app.wallPts(1,2,w))];
                                    slopeVect = slopeVect/norm(slopeVect); % unit vector
                                    velVect = [velX0(m) velY0(m)];
                                    
                                    % resolve velX and velY along line-of-collision coordinates
                                    velParallel = dot(velVect,slopeVect)*slopeVect;
                                    velPerpendicular = velVect - velParallel;
                
                                    % reverse parallel velocity
                                    velParallel = -velParallel;
                
                                    % check if new velocity moves particle away from wall
                                    if dot(velParallel,slopeVect) < 0
                                        velParallel = -velParallel;
                                    end
                
                                    % resolve velocities in velX and velY directions
                                    velX0(m) = velParallel(1)+velPerpendicular(1);
                                    velY0(m) = velParallel(2)+velPerpendicular(2);
    
                                    % reflect X and Y coordinates
                                    d = size((m>app.nPart_A)+1)/2 - sqrt((X(i,m) - app.wallPts(1,1,w))^2 + (Y(i,m) - app.wallPts(1,2,w))^2);
                                    X(i,m) = X(i,m) + 2*slopeVect(1)*d;
                                    Y(i,m) = Y(i,m) + 2*slopeVect(2)*d;
                                end
                                if isCollisionWallEnd(m,2,w) == 1
                                    slopeVect = [(X(i,m) - app.wallPts(2,1,w)) (Y(i,m) - app.wallPts(2,2,w))];
                                    slopeVect = slopeVect/norm(slopeVect); % unit vector
                                    velVect = [velX0(m) velY0(m)];
                                    
                                    % resolve velX and velY along line-of-collision coordinates
                                    velParallel = dot(velVect,slopeVect)*slopeVect;
                                    velPerpendicular = velVect - velParallel;
                
                                    % reverse parallel velocity
                                    velParallel = -velParallel;
                
                                    % check if new velocity moves particle away from wall
                                    if dot(velParallel,slopeVect) < 0
                                        velParallel = -velParallel;
                                    end
                
                                    % resolve velocities in velX and velY directions
                                    velX0(m) = velParallel(1)+velPerpendicular(1);
                                    velY0(m) = velParallel(2)+velPerpendicular(2);
    
                                    % reflect X and Y coordinates
                                    d = size((m>app.nPart_A)+1)/2 - sqrt((X(i,m) - app.wallPts(2,1,w))^2 + (Y(i,m) - app.wallPts(2,2,w))^2);
                                    X(i,m) = X(i,m) + 2*slopeVect(1)*d;
                                    Y(i,m) = Y(i,m) + 2*slopeVect(2)*d;
                                end
                
                                if isCollisionWallEdge(w,m) == 1
                                    velVect = [velX0(m) velY0(m)];
                                    wallVect = [app.wallPts(2,1,w)-app.wallPts(1,1,w) app.wallPts(2,2,w)-app.wallPts(1,2,w)];
                                    wallVect = wallVect/norm(wallVect); % unit vector
                                    
                                    % reflect the velocity from wall
                                    velReflected = 2*dot(velVect,wallVect)*wallVect - velVect;
                
                                    % resolve velocities in velX and velY directions
                                    velX0(m) = velReflected(1);
                                    velY0(m) = velReflected(2);
    
                                    % reflect X and Y coordinates
                                    wallEndToPartVect = [(X(i,m) - app.wallPts(1,1,w)) (Y(i,m) - app.wallPts(1,2,w))];
                                    wallEdgeToPartVect = wallEndToPartVect - dot(wallEndToPartVect,wallVect)*wallVect;
                                    wallEdgeToPart = norm(wallEdgeToPartVect);
                                    normalVect = wallEdgeToPartVect/wallEdgeToPart;
                                    d = size((m>app.nPart_A)+1)/2 - wallEdgeToPart;
                                    X(i,m) = X(i,m) + 2*normalVect(1)*d;
                                    Y(i,m) = Y(i,m) + 2*normalVect(2)*d;
                                end
                            end
                        end
    
                        cellX = ceil((app.box_xmax/2 + X(i,m))/cellSize_x); 
                        cellY = ceil((app.box_ymax/2 + Y(i,m))/cellSize_y);
                
                        for x = (cellX-cellSearchRange):(cellX+cellSearchRange)
                            for y = (cellY-cellSearchRange):(cellY+cellSearchRange)
                                if x >= 1 && x <= cellNos_x && y >= 1 && y <= cellNos_y
                                    if lookup(x,y,1) ~= 0
                                        for n = lookup(x,y,lookupCtr(x,y)-1)
                                            if m~=n
                                                distPart = sqrt((X(i,m) - X(i,n))^2 + (Y(i,m) - Y(i,n))^2);
                                                isCollisionPart = distPart < size((m>app.nPart_A)+1)/2 + size((n>app.nPart_A)+1)/2;
                                                if (isCollisionPart == 1) || (isCollisionPart == 0 && app.reactiveGas == 1 && isMolecule(m) == isMolecule(n) && isMolecule(m) ~= 0)
                                                    slopeVect = [(X(i,m) - X(i,n)) (Y(i,m) - Y(i,n))];
                                                    slopeVect = slopeVect/norm(slopeVect); % unit vector
                                                    velVect_m = [velX0(m) velY0(m)];
                                                    velVect_n = [velX0(n) velY0(n)];
                                                    m1 = massPart((m>app.nPart_A)+1);
                                                    m2 = massPart((n>app.nPart_A)+1);
                                    
                                                    % resolve velX and velY along line-of-collision coordinates
                                                    velParallel = [dot(velVect_m,slopeVect)*slopeVect; dot(velVect_n,slopeVect)*slopeVect];
                                                    velParallelMag = [dot(velVect_m,slopeVect); dot(velVect_n,slopeVect)];
                                                    velPerpendicular = [velVect_m; velVect_n] - velParallel;
                                    
                                                    % check if reaction occured
                                                    if app.reactiveGas == 1 && isMolecule(m) == 0 && isMolecule(n) == 0
                                                        if 0.5*(m1*(norm(velParallel(1,:)))^2 + m2*(norm(velParallel(2,:)))^2) >= (app.criticalEnergy * (4184*1000*(10^-10)))
                                                            molCtr = molCtr + 1;
                                                            isMolecule(m) = molCtr; isMolecule(n) = molCtr;
                                                            if (app.selfReact_A == 0 && m <= app.nPart_A && n <= app.nPart_A) || (app.selfReact_B == 0 && m > app.nPart_A && n > app.nPart_A)
                                                                molCtr = molCtr - 1;
                                                                isMolecule(m) = 0; isMolecule(n) = 0;
                                                            end

                                                            % Update species count
                                                            if isMolecule(m) ~= 0 && isMolecule(n) ~= 0
                                                                if m <= app.nPart_A && n <= app.nPart_A
                                                                    speciesHistory(i:end,3) = speciesHistory(i:end,3) + 1;
                                                                    speciesHistory(i:end,1) = speciesHistory(i:end,1) - 2;
                                                                elseif m > app.nPart_A && n > app.nPart_A
                                                                    speciesHistory(i:end,4) = speciesHistory(i:end,4) + 1;
                                                                    speciesHistory(i:end,2) = speciesHistory(i:end,2) - 2;
                                                                else
                                                                    speciesHistory(i:end,5) = speciesHistory(i:end,5) + 1;
                                                                    speciesHistory(i:end,1) = speciesHistory(i:end,1) - 1;
                                                                    speciesHistory(i:end,2) = speciesHistory(i:end,2) - 1;
                                                                end
                                                            end
                                                        end
                                                    end
                                    
                                                    % exchange momentum
                                                    oldVelParallel = velParallel([1 2],:);
                                                    v1f = ((m1-m2)*velParallelMag(1) + 2*m2*velParallelMag(2))/(m1+m2);
                                                    v2f = ((m2-m1)*velParallelMag(2) + 2*m1*velParallelMag(1))/(m1+m2);
                                                    velParallel = [v1f*slopeVect; v2f*slopeVect];
                                    
                                                    % check if velocities separate/join particles
                                                    if (isCollisionPart == 1) && (dot(slopeVect, velParallel(1,:) - velParallel(2,:)) < 0)
                                                        velParallel([1 2],:) = oldVelParallel;
                                                    elseif (isCollisionPart == 0 && app.reactiveGas == 1 && isMolecule(m) == isMolecule(n) && isMolecule(m) ~= 0) && (dot(slopeVect, velParallel(1,:) - velParallel(2,:)) > 0)
                                                            velParallel([1 2],:) = oldVelParallel;
                                                    end
                                    
                                                    % resolve velocities in velX and velY directions
                                                    velX0([m n]) = [velParallel(1,1)+velPerpendicular(1,1), velParallel(2,1)+velPerpendicular(2,1)];
                                                    velY0([m n]) = [velParallel(1,2)+velPerpendicular(1,2), velParallel(2,2)+velPerpendicular(2,2)];
                                                end
                                            end
                                        end
                                    end
                                end
                            end
                        end
                    end
                    
                    % add -Y velocity increase due to gravity
                    if app.gravity == 1
                        velY0 = velY0 - (app.g*(10^10)/(10^30))*app.timeStep;
                    end
    
                    % update velocity array
                    velX(i,:) = velX0;
                    velY(i,:) = velY0;
                end
                
                %% Evaluate thermo-physical quantities
                % calculate particle speed, pressure on top wall and region concentration/internal energy
                app.speedArray = zeros(itr,length(initX));
                app.pressure = zeros(itr,1);
                if app.probeRegion == 1
                    app.concentration = zeros(itr,2);
                    app.energy = zeros(itr,2);
                end
                for i = 1:itr
                    % speed
                    app.speedArray(i,:) = sqrt(velX(i,:).^2 + velY(i,:).^2);
                
                    % pressure on top wall
                    if i>1
                        app.pressure(i) = (massPart(1)*dot(velY(i-1,1:app.nPart_A),isTopWallCollision(i,1:app.nPart_A))*2)/app.timeStep/app.box_ymax * (0.001/(6.023*10^23)*10^30); % (m*dv/dt)/L [N/m]
                        if app.twoParticleMix == 1
                            app.pressure(i) = app.pressure(i) + (massPart(2)*dot(velY(i-1,app.nPart_A+1:end),isTopWallCollision(i,app.nPart_A+1:end))*2)/app.timeStep/app.box_ymax * (0.001/(6.023*10^23)*10^30);
                        end
                    end
                    
                    if app.probeRegion == 1
                        % region concentration/internal energy
                        in_A = inpolygon(X(i,1:app.nPart_A),Y(i,1:app.nPart_A),app.probeRectCoord_X,app.probeRectCoord_Y);
                        app.concentration(i,1) = sum(in_A)/(app.probeRect(3)*app.probeRect(4)*10^-20); % particles/m2
                        app.energy(i,1) = sum(0.5 * app.massPart_A * (app.speedArray(i,1:app.nPart_A).*in_A).^2 * 0.001/6.023/10^23 * 10^10); % J
                        if app.twoParticleMix == 1
                            in_B = inpolygon(X(i,app.nPart_A+1:end),Y(i,app.nPart_A+1:end),app.probeRectCoord_X,app.probeRectCoord_Y);
                            app.concentration(i,2) = sum(in_B)/(app.probeRect(3)*app.probeRect(4)*10^-20); % particles/m2
                            app.energy(i,2) = sum(0.5 * app.massPart_B * (app.speedArray(i,app.nPart_A+1:end).*in_B).^2 * 0.001/6.023/10^23 * 10^10); % J
                        end
                    end
                end
    
                app.StatusEditField.Value = "Complete";
                
                %% Plot thermo-physical and chemical quantities
                appAxes = app.UIAxes;
                appAxes.NextPlot = 'replace';
                plot(appAxes, movmean(app.pressure,app.movingAverageWindow));
                appAxes.Title.String = "Wall-averaged moving mean pressure";
                appAxes.XLabel.String = "Time (in fs)";
                appAxes.YLabel.String = "Pressure (in N/m)";
                appAxes.XGrid = "on";
                appAxes.YGrid = "on";
                app.TopwallpressureButton.Value = 1;

                appAxes = app.UIAxes2;
                appAxes.NextPlot = 'replace';
                plot(appAxes, speciesHistory(:,1), LineWidth=1, Color="blue");
                appAxes.NextPlot = "add";
                plot(appAxes, speciesHistory(:,2), LineWidth=1, Color="red");
                appAxes.NextPlot = "add";
                plot(appAxes, speciesHistory(:,3), LineWidth=1, Color="blue", LineStyle="--");
                appAxes.NextPlot = "add";
                plot(appAxes, speciesHistory(:,4), LineWidth=1, Color="red", LineStyle="--");
                appAxes.NextPlot = "add";
                plot(appAxes, speciesHistory(:,5), LineWidth=1, Color="magenta", LineStyle="--");
                appAxes.Title.String = "Chemical species time history";
                appAxes.XLabel.String = "Time (in fs)";
                appAxes.YLabel.String = "No. of atoms/molecules";
                appAxes.XGrid = "on";
                appAxes.YGrid = "on";
                legend(appAxes, "Blue monoatomic gas","Red monoatomic gas", "Blue diatomic gas", "Red diatomic gas", "Blue-red diatomic gas");
    
                %% Animate particles and make video
    
                fig = figure();
                fig.WindowState = 'maximized';
                drawnow; % Force MATLAB to update the figure size
                
                % Set axis properties first
                axis equal;
                axis([-app.box_xmax/2 - 10, app.box_xmax/2 + 10, -app.box_ymax/2 - 10, app.box_ymax/2 + 10]);
                xticks(-app.box_xmax/2:10:app.box_xmax/2);
                yticks(-app.box_ymax/2:10:app.box_ymax/2);
                grid on;
                hold on;
                
                % Plot walls (if any)
                if app.innerPartitions == 1
                    for w = 1:app.noOfWalls
                        line(app.wallPts(:,1,w), app.wallPts(:,2,w), 'Color', 'black','LineWidth',1.5);
                    end
                end
                rectangle('Position',[-app.box_xmax/2,-app.box_ymax/2,app.box_xmax,app.box_ymax],'LineWidth',1.5);
                if app.heatTransferBottom == 1
                    line([-app.box_xmax/2 app.box_xmax/2], [-app.box_ymax/2 -app.box_ymax/2], 'Color', 'yellow','LineWidth',1.5);
                end
                
                % SCALING CALCULATION
                ax = gca;
                drawnow; % Force the figure to render to get accurate measurements
                
                % Get axis position in pixels (more reliable than points)
                set(ax, 'Units', 'pixels');
                axPos = get(ax, 'Position');
                set(ax, 'Units', 'normalized'); % Reset to default
                
                % Get axis data limits
                xLimits = xlim(ax);
                yLimits = ylim(ax);
                
                % Calculate pixels per data unit
                pixelsPerDataUnit_X = axPos(3) / diff(xLimits); % pixels per x-unit
                pixelsPerDataUnit_Y = axPos(4) / diff(yLimits); % pixels per y-unit
                
                % Use the smaller scaling to maintain circular particles in axis equal
                pixelsPerDataUnit = min(pixelsPerDataUnit_X, pixelsPerDataUnit_Y);
                
                % Convert pixels to points (72 points = 1 inch, typical screen = 96 DPI)
                screenDPI = get(0, 'ScreenPixelsPerInch'); % Get screen DPI
                pointsPerPixel = 72 / screenDPI;
                
                % Calculate marker sizes in points^2 (area units for scatter)
                diameter_A_pixels = app.size_A * pixelsPerDataUnit;
                diameter_B_pixels = app.size_B * pixelsPerDataUnit;
                
                diameter_A_points = diameter_A_pixels * pointsPerPixel;
                diameter_B_points = diameter_B_pixels * pointsPerPixel;
                
                % Convert diameter to area (scatter uses area, not diameter)
                markerSize_A = (diameter_A_points)^2;
                markerSize_B = (diameter_B_points)^2;
            
                % Create scatter plots (not regular plot)
                s_A = scatter(X(1,1:app.nPart_A), Y(1,1:app.nPart_A), markerSize_A, 'blue', 'filled', 'MarkerEdgeColor', 'blue');
                
                if app.twoParticleMix == 1
                    s_B = scatter(X(1,app.nPart_A+1:end), Y(1,app.nPart_A+1:end), markerSize_B, 'red', 'filled', 'MarkerEdgeColor', 'red');
                end
                
                title("Press any key to start");
                pause;
                title("Animation started");
    
                if app.makeVideo == 1
                    v = VideoWriter('gas');
                    v.Quality = 100;
                    open(v);
                end
            
                for i = 2:app.simSpeedUp:itr
                    % w = waitforbuttonpress;
                    % if w == 1
                    %     % Update scatter positions (not markersize)
                    %     set(s_A, 'XData', X(i,1:nPart_A), 'YData', Y(i,1:nPart_A));
                    %     if twoParticleMix == 1
                    %         set(s_B, 'XData', X(i,nPart_A+1:end), 'YData', Y(i,nPart_A+1:end));
                    %     end
                    %     drawnow;
                    % end
                
                    % Update scatter positions (not markersize)
                    set(s_A, 'XData', X(i,1:app.nPart_A), 'YData', Y(i,1:app.nPart_A));
                    if app.twoParticleMix == 1
                        set(s_B, 'XData', X(i,app.nPart_A+1:end), 'YData', Y(i,app.nPart_A+1:end));
                    end
                    drawnow;
                    
                    if app.makeVideo == 1
                        frame = getframe(gcf);
                        writeVideo(v , frame);
                    end
                end
                
                title("Animation ended");
    
                if app.makeVideo == 1
                    close(v);
                end
            else
                % Particle
                massPart = app.massPart_A; % mass of particles [amu OR gm/mol]
                size = app.size_A; % particle diameter [angstrom]
                inlet_velocity = app.speed_A; % inlet fluid flow velocity [angstrom/femtosecond]

                %% Initialise particles' state

                itr = floor(app.timeTotal/app.timeStep);
                nX = floor((app.box_xmax/size)/app.partSpace) + app.partExcess;
                nY = floor((app.box_ymax/size)/app.partSpace);
                nPart = nX*nY;
                xloc = linspace(-app.box_xmax/2 + size/2 - (nX-1)*app.partSpace*size,-app.box_xmax/2 + size/2,nX);
                yloc = linspace(-app.box_ymax/2 + size/2,app.box_ymax/2 - size/2,nY);
                dY = yloc(2) - yloc(1);
                ctrY = 1;
                minX = min(xloc)-app.partSpace*size;
                [initX, initY] = meshgrid(xloc,yloc);
                initX = reshape(initX,1,[]);
                initY = reshape(initY,1,[]);
                initAngle = zeros(1,nPart); % initial angle w.r.t x-axis
                initSpeed = inlet_velocity*ones(1,nPart); % initial speed in units/iteration
                if app.innerPartitions == 1
                    isCollisionWallEnd = zeros(nPart,2,app.noOfWalls); % wall end collision detection matrix
                    isCollisionWallEdge = zeros(app.noOfWalls,nPart); % wall edge collision detection matrix
                end
                cellSize_x = app.box_xmax/floor(app.box_xmax/size); % Hash grid cell size
                cellSize_y = app.box_ymax/floor(app.box_ymax/size);
                cellNos_x = floor(app.box_xmax/size); % No. of cells
                cellNos_y = floor(app.box_ymax/size);
                cellSearchRange = 1;
                
                % Making the wall box
                if app.innerPartitions == 1
                    wallNormalVect = zeros(app.noOfWalls,2); % unit wall normal vector
                    for w = 1:app.noOfWalls
                        wallNormalVect(w,:) = [-(app.wallPts(2,2,w)-app.wallPts(1,2,w)), app.wallPts(2,1,w)-app.wallPts(1,1,w)];
                        wallNormalVect(w,:) = wallNormalVect(w,:)/norm(wallNormalVect(w,:));
                    end
                    addVect = (size(1)/2)*wallNormalVect;
                    wallBox = zeros(4,2,app.noOfWalls);
                    for w = 1:app.noOfWalls
                        wallBox(:,:,w) = [app.wallPts(1,:,w)+addVect(w,:); app.wallPts(1,:,w)-addVect(w,:); app.wallPts(2,:,w)-addVect(w,:); app.wallPts(2,:,w)+addVect(w,:)];
                    end
                end
                
                %% Simulate particles
                X = zeros(itr,length(initX));
                Y = zeros(itr,length(initY));
                X(1,:) = initX;
                Y(1,:) = initY;
                
                velX = zeros(itr,length(initX));
                velY = zeros(itr,length(initY));
                velX(1,:) = initSpeed.*cosd(initAngle);
                velY(1,:) = initSpeed.*sind(initAngle);
                velX0 = velX(1,:);
                velY0 = velY(1,:);
                
                xmax = app.box_xmax/2 - size/2;
                xmin = -xmax;
                ymax = app.box_ymax/2 - size/2;
                ymin = -ymax;
                
                % calculate particles' coordinates for all times
                for i = 2:itr
                    % move particle and particle append flag (if windTunnel is on)
                    X(i,:) = X(i-1,:) + velX0*app.timeStep;
                    Y(i,:) = Y(i-1,:) + velY0*app.timeStep;
                    minX = minX + inlet_velocity*app.timeStep;
                
                    % Indices for particles inside the box
                    partI = find(X(i,:)>=xmin & X(i,:)<=xmax);
                
                    % collision with box wall
                    velY0 = velY0.*((((Y(i,:) > ymax) | (Y(i,:) < ymin)) - 0.5)/(-0.5));
                    Y(i,:) = Y(i,:) - 2*((Y(i,:) > ymax).*(Y(i,:) - ymax));
                    Y(i,:) = Y(i,:) + 2*((Y(i,:) < ymin).*(ymin - Y(i,:)));
                
                    % check inside wall edge collision
                    if app.innerPartitions == 1
                        for w = 1:app.noOfWalls
                            in = inpolygon(X(i,partI),Y(i,partI),wallBox(:,1,w),wallBox(:,2,w));
                            isCollisionWallEdge(w,partI) = in;
                        end
                    end
                
                    % reset grid
                    lookup = zeros(cellNos_x,cellNos_y);
                    lookupCtr = ones(cellNos_x,cellNos_y);
                
                    % assign particles to grid
                    for p = partI
                        cellX = ceil((app.box_xmax/2 + X(i,p))/cellSize_x); 
                        cellY = ceil((app.box_ymax/2 + Y(i,p))/cellSize_y);
                        lookup(cellX,cellY,lookupCtr(cellX,cellY)) = p;
                        lookupCtr(cellX,cellY) = lookupCtr(cellX,cellY) + 1;
                    end
                
                    % check neighbouring cells for each particle and register collision
                    for m = partI
                        if app.innerPartitions == 1
                            for w = 1:app.noOfWalls
                                distWallEnd1 = sqrt((app.wallPts(1,1,w) - X(i,m))^2 + (app.wallPts(1,2,w) - Y(i,m))^2);
                                distWallEnd2 = sqrt((app.wallPts(2,1,w) - X(i,m))^2 + (app.wallPts(2,2,w) - Y(i,m))^2);
                                isCollisionWallEnd(m,:,w) = [distWallEnd1 distWallEnd2] < (size/2)*ones(1,2);
                
                                % remove overlapping wall detection for end and edge regions
                                isCollisionWallEnd(m,1,w) = (~(isCollisionWallEnd(m,1,w) & isCollisionWallEdge(w,m)) & isCollisionWallEnd(m,1,w));
                                isCollisionWallEnd(m,2,w) = (~(isCollisionWallEnd(m,2,w) & isCollisionWallEdge(w,m)) & isCollisionWallEnd(m,2,w));
                                
                                if isCollisionWallEnd(m,1,w) == 1
                                    slopeVect = [(X(i,m) - app.wallPts(1,1,w)) (Y(i,m) - app.wallPts(1,2,w))];
                                    slopeVect = slopeVect/norm(slopeVect); % unit vector
                                    velVect = [velX0(m) velY0(m)];
                                    
                                    % resolve velX and velY along line-of-collision coordinates
                                    velParallel = dot(velVect,slopeVect)*slopeVect;
                                    velPerpendicular = velVect - velParallel;
                
                                    % reverse parallel velocity
                                    velParallel = -velParallel;
                
                                    % check if new velocity moves particle away from wall
                                    if dot(velParallel,slopeVect) < 0
                                        velParallel = -velParallel;
                                    end
                
                                    % resolve velocities in velX and velY directions
                                    velX0(m) = velParallel(1)+velPerpendicular(1);
                                    velY0(m) = velParallel(2)+velPerpendicular(2);
                
                                    % reflect X and Y coordinates
                                    d = size/2 - sqrt((X(i,m) - app.wallPts(1,1,w))^2 + (Y(i,m) - app.wallPts(1,2,w))^2);
                                    X(i,m) = X(i,m) + 2*slopeVect(1)*d;
                                    Y(i,m) = Y(i,m) + 2*slopeVect(2)*d;
                                end
                                if isCollisionWallEnd(m,2,w) == 1
                                    slopeVect = [(X(i,m) - app.wallPts(2,1,w)) (Y(i,m) - app.wallPts(2,2,w))];
                                    slopeVect = slopeVect/norm(slopeVect); % unit vector
                                    velVect = [velX0(m) velY0(m)];
                                    
                                    % resolve velX and velY along line-of-collision coordinates
                                    velParallel = dot(velVect,slopeVect)*slopeVect;
                                    velPerpendicular = velVect - velParallel;
                
                                    % reverse parallel velocity
                                    velParallel = -velParallel;
                
                                    % check if new velocity moves particle away from wall
                                    if dot(velParallel,slopeVect) < 0
                                        velParallel = -velParallel;
                                    end
                
                                    % resolve velocities in velX and velY directions
                                    velX0(m) = velParallel(1)+velPerpendicular(1);
                                    velY0(m) = velParallel(2)+velPerpendicular(2);
                
                                    % reflect X and Y coordinates
                                    d = size/2 - sqrt((X(i,m) - app.wallPts(2,1,w))^2 + (Y(i,m) - app.wallPts(2,2,w))^2);
                                    X(i,m) = X(i,m) + 2*slopeVect(1)*d;
                                    Y(i,m) = Y(i,m) + 2*slopeVect(2)*d;
                                end
                
                                if isCollisionWallEdge(w,m) == 1
                                    velVect = [velX0(m) velY0(m)];
                                    wallVect = [app.wallPts(2,1,w)-app.wallPts(1,1,w) app.wallPts(2,2,w)-app.wallPts(1,2,w)];
                                    wallVect = wallVect/norm(wallVect); % unit vector
                                    
                                    % reflect the velocity from wall
                                    velReflected = 2*dot(velVect,wallVect)*wallVect - velVect;
                
                                    % resolve velocities in velX and velY directions
                                    velX0(m) = velReflected(1);
                                    velY0(m) = velReflected(2);
                
                                    % reflect X and Y coordinates
                                    wallEndToPartVect = [(X(i,m) - app.wallPts(1,1,w)) (Y(i,m) - app.wallPts(1,2,w))];
                                    wallEdgeToPartVect = wallEndToPartVect - dot(wallEndToPartVect,wallVect)*wallVect;
                                    wallEdgeToPart = norm(wallEdgeToPartVect);
                                    normalVect = wallEdgeToPartVect/wallEdgeToPart;
                                    d = size/2 - wallEdgeToPart;
                                    X(i,m) = X(i,m) + 2*normalVect(1)*d;
                                    Y(i,m) = Y(i,m) + 2*normalVect(2)*d;
                                end
                            end
                        end
                
                        cellX = ceil((app.box_xmax/2 + X(i,m))/cellSize_x); 
                        cellY = ceil((app.box_ymax/2 + Y(i,m))/cellSize_y);
                
                        for x = (cellX-cellSearchRange):(cellX+cellSearchRange)
                            for y = (cellY-cellSearchRange):(cellY+cellSearchRange)
                                if x >= 1 && x <= cellNos_x && y >= 1 && y <= cellNos_y
                                    if lookup(x,y,1) ~= 0
                                        for n = lookup(x,y,lookupCtr(x,y)-1)
                                            if m~=n
                                                distPart = sqrt((X(i,m) - X(i,n))^2 + (Y(i,m) - Y(i,n))^2);
                                                isCollisionPart = distPart < size;
                                                if (isCollisionPart == 1)
                                                    slopeVect = [(X(i,m) - X(i,n)) (Y(i,m) - Y(i,n))];
                                                    slopeVect = slopeVect/norm(slopeVect); % unit vector
                                                    velVect_m = [velX0(m) velY0(m)];
                                                    velVect_n = [velX0(n) velY0(n)];
                                    
                                                    % resolve velX and velY along line-of-collision coordinates
                                                    velParallel = [dot(velVect_m,slopeVect)*slopeVect; dot(velVect_n,slopeVect)*slopeVect];
                                                    velPerpendicular = [velVect_m; velVect_n] - velParallel;
                                    
                                                    % exchange momentum
                                                    oldVelParallel = velParallel([1 2],:);
                                                    velParallel([1 2],:) = velParallel([2 1],:);
                                    
                                                    % check if velocities separate/join particles
                                                    if (isCollisionPart == 1) && (dot(slopeVect, velParallel(1,:) - velParallel(2,:)) < 0)
                                                        velParallel([1 2],:) = oldVelParallel;
                                                    end
                                    
                                                    % resolve velocities in velX and velY directions
                                                    velX0([m n]) = [velParallel(1,1)+velPerpendicular(1,1), velParallel(2,1)+velPerpendicular(2,1)];
                                                    velY0([m n]) = [velParallel(1,2)+velPerpendicular(1,2), velParallel(2,2)+velPerpendicular(2,2)];
                                                end
                                            end
                                        end
                                    end
                                end
                            end
                        end
                    end
                
                    % add -Y velocity increase due to gravity
                    if app.gravity == 1
                        velY0(partI) = velY0(partI) - (app.g*(10^10)/(10^30))*app.timeStep;
                    end
                
                    % Detect exited particles and transfer them at inlet
                    partO = find(X(i,:)>xmax);
                    for p = partO
                        if ctrY > nY
                            minX = minX - app.partSpace*size;
                            ctrY = 1;
                        end
                        X(i,p) = minX;
                        Y(i,p) = ymin + (ctrY-1)*dY;
                        velX0(p) = inlet_velocity;
                        velY0(p) = 0;
                        ctrY = ctrY + 1;
                    end
                
                    % update velocity array
                    velX(i,:) = velX0;
                    velY(i,:) = velY0;
                end
                
                app.StatusEditField.Value = "Complete";

                %% Evaluate thermo-physical quantities
                
                % calculate particle speed, pressure on top wall and region concentration/internal energy
                app.speedArray = zeros(itr,length(initX));
                if app.probeRegion == 1
                    app.concentration = zeros(itr);
                    app.energy = zeros(itr);
                end
                for i = 1:itr
                    % speed
                    app.speedArray(i,:) = sqrt(velX(i,:).^2 + velY(i,:).^2);
                
                    if app.probeRegion == 1
                        % region concentration/internal energy
                        partI = find(X(i,:)>=xmin & X(i,:)<=xmax);
                        in = inpolygon(X(i,partI),Y(i,partI),app.probeRectCoord_X,app.probeRectCoord_Y);
                        app.concentration(i) = sum(in)/(app.probeRect(3)*app.probeRect(4)*10^-20); % particles/m2
                        app.energy(i) = sum(0.5 * massPart * (app.speedArray(i,partI).*in).^2 * 0.001/6.023/10^23 * 10^10); % J
                    end
                end
                
                %% Animate particles and make video
                
                fig = figure();
                fig.WindowState = 'maximized';
                drawnow; % Force MATLAB to update the figure size
                
                % Set axis properties first
                axis equal;
                axis([-app.box_xmax/2 - 10, app.box_xmax/2 + 10, -app.box_ymax/2 - 10, app.box_ymax/2 + 10]);
                xticks(-app.box_xmax/2:10:app.box_xmax/2);
                yticks(-app.box_ymax/2:10:app.box_ymax/2);
                grid on;
                hold on;
                
                % Plot walls (if any)
                if app.innerPartitions == 1
                    for w = 1:app.noOfWalls
                        line(app.wallPts(:,1,w), app.wallPts(:,2,w), 'Color', 'black','LineWidth',1.5);
                    end
                end
                rectangle('Position',[-app.box_xmax/2,-app.box_ymax/2,app.box_xmax,app.box_ymax],'LineWidth',1.5);
                
                % SCALING CALCULATION
                ax = gca;
                drawnow; % Force the figure to render to get accurate measurements
                
                % Get axis position in pixels (more reliable than points)
                set(ax, 'Units', 'pixels');
                axPos = get(ax, 'Position');
                set(ax, 'Units', 'normalized'); % Reset to default
                
                % Get axis data limits
                xLimits = xlim(ax);
                yLimits = ylim(ax);
                
                % Calculate pixels per data unit
                pixelsPerDataUnit_X = axPos(3) / diff(xLimits); % pixels per x-unit
                pixelsPerDataUnit_Y = axPos(4) / diff(yLimits); % pixels per y-unit
                
                % Use the smaller scaling to maintain circular particles in axis equal
                pixelsPerDataUnit = min(pixelsPerDataUnit_X, pixelsPerDataUnit_Y);
                
                % Convert pixels to points (72 points = 1 inch, typical screen = 96 DPI)
                screenDPI = get(0, 'ScreenPixelsPerInch'); % Get screen DPI
                pointsPerPixel = 72 / screenDPI;
                
                % Calculate marker sizes in points^2 (area units for scatter)
                diameter_pixels = size * pixelsPerDataUnit;
                
                diameter_points = diameter_pixels * pointsPerPixel;
                
                % Convert diameter to area (scatter uses area, not diameter)
                markerSize = (diameter_points)^2;
            
                % Create scatter plots (not regular plot)
                partI = find(X(1,:)>=xmin & X(1,:)<=xmax);
                s = scatter(X(1,partI), Y(1,partI), markerSize, 'blue', 'filled', 'MarkerEdgeColor', 'blue');
                
                title("Press any key to start");
                pause;
                title("Animation started");
            
                if app.makeVideo == 1
                    v = VideoWriter('gas');
                    v.Quality = 100;
                    open(v);
                end
            
                for i = 2:app.simSpeedUp:itr
                    partI = find(X(i,:)>=xmin & X(i,:)<=xmax);
                    
                    % w = waitforbuttonpress;
                    % if w == 1
                    %     % Update scatter positions (not markersize)
                    %     set(s, 'XData', X(i,partI), 'YData', Y(i,partI));
                    %     drawnow;
                    % end
                
                    % Update scatter positions (not markersize)
                    set(s, 'XData', X(i,partI), 'YData', Y(i,partI));
                    drawnow;
            
                    if app.makeVideo == 1
                        frame = getframe(gcf);
                        writeVideo(v , frame);
                    end
                end
                
                title("Animation ended");
            
                if app.makeVideo == 1
                    close(v);
                end
            end
        end

        % Selection changed function: SelectagraphButtonGroup
        function SelectagraphButtonGroupSelectionChanged(app, event)
            selectedButton = app.SelectagraphButtonGroup.SelectedObject;
            
            if selectedButton.Text == "Top wall pressure"
                if app.gasFlow == 0
                    appAxes = app.UIAxes;
                    appAxes.NextPlot = 'replace';
                    plot(appAxes, movmean(app.pressure,app.movingAverageWindow));
                    appAxes.Title.String = "Wall-averaged moving mean pressure";
                    appAxes.XLabel.String = "Time (in fs)";
                    appAxes.YLabel.String = "Pressure (in N/m)";
                    legend(appAxes, 'off');
                    appAxes.XGrid = "on";
                    appAxes.YGrid = "on";
                end
            elseif selectedButton.Text == "Speed distribution"
                if app.gasFlow == 0
                    appAxes = app.UIAxes;
                    appAxes.NextPlot = 'replace';
                    histogram(appAxes, app.speedArray(end,:), 'Normalization', 'probability');
                    appAxes.Title.String = "Particle speed distribution";
                    appAxes.XLabel.String = "Speed (in Å/fs)";
                    appAxes.YLabel.String = "Fraction of particles";
                    legend(appAxes, 'off');
                    appAxes.XGrid = "on";
                    appAxes.YGrid = "on";
                end
            elseif selectedButton.Text == "Region internal energy" && app.probeRegion == 1
                if app.gasFlow == 0
                    appAxes = app.UIAxes;
                    appAxes.NextPlot = 'replace';
                    plot(appAxes, app.energy(:,1));
                    appAxes.NextPlot = 'add';
                    plot(appAxes, app.energy(:,2));
                    appAxes.Title.String = "Region internal energy";
                    appAxes.XLabel.String = "Time (in fs)";
                    appAxes.YLabel.String = "Energy (in J)";
                    legend(appAxes, "Blue particle","Red particle");
                    appAxes.XGrid = "on";
                    appAxes.YGrid = "on";
                else
                    appAxes = app.UIAxes;
                    appAxes.NextPlot = 'replace';
                    plot(appAxes, app.energy);
                    appAxes.Title.String = "Region internal energy";
                    appAxes.XLabel.String = "Time (in fs)";
                    appAxes.YLabel.String = "Energy (in J)";
                    appAxes.XGrid = "on";
                    appAxes.YGrid = "on";
                end
            elseif selectedButton.Text == "Region concentration" && app.probeRegion == 1
                if app.gasFlow == 0
                    appAxes = app.UIAxes;
                    appAxes.NextPlot = 'replace';
                    plot(appAxes, app.concentration(:,1));
                    appAxes.NextPlot = 'add';
                    plot(appAxes, app.concentration(:,2));
                    appAxes.Title.String = "Region particle concentration";
                    appAxes.XLabel.String = "Time (in fs)";
                    appAxes.YLabel.String = "Concentration (in particles/m2)";
                    legend(appAxes, "Blue particle","Red particle");
                    appAxes.XGrid = "on";
                    appAxes.YGrid = "on";
                else
                    appAxes = app.UIAxes;
                    appAxes.NextPlot = 'replace';
                    plot(appAxes, app.concentration);
                    appAxes.Title.String = "Region particle concentration";
                    appAxes.XLabel.String = "Time (in fs)";
                    appAxes.YLabel.String = "Concentration (in particles/m2)";
                    appAxes.XGrid = "on";
                    appAxes.YGrid = "on";
                end
            end
        end

        % Value changed function: IncludegravityCheckBox
        function IncludegravityCheckBoxValueChanged(app, event)
            value = app.IncludegravityCheckBox.Value;
            if value == 1
                app.AcclelerationduetogravityEditField.Visible = "on";
                app.AcclelerationduetogravityEditFieldLabel.Visible = "on";
                app.ms2Label.Visible = "on";
                app.gravity = value;
            else
                app.AcclelerationduetogravityEditField.Visible = "off";
                app.AcclelerationduetogravityEditFieldLabel.Visible = "off";
                app.ms2Label.Visible = "off";
                app.gravity = value;
            end
        end

        % Value changed function: AcclelerationduetogravityEditField
        function AcclelerationduetogravityEditFieldValueChanged(app, event)
            value = app.AcclelerationduetogravityEditField.Value;
            app.g = value;
        end

        % Value changed function: ReactivegasCheckBox
        function ReactivegasCheckBoxValueChanged(app, event)
            value = app.ReactivegasCheckBox.Value;
            if value == 1
                app.ThresholdcollisionenergyEditField.Visible = "on";
                app.ThresholdcollisionenergyEditFieldLabel.Visible = "on";
                app.SelfreactingbluegasCheckBox.Visible = "on";
                app.SelfreactingredgasCheckBox.Visible = "on";
                app.kcalmolLabel.Visible = "on";
                app.reactiveGas = value;
            else
                app.ThresholdcollisionenergyEditField.Visible = "off";
                app.ThresholdcollisionenergyEditFieldLabel.Visible = "off";
                app.SelfreactingbluegasCheckBox.Visible = "off";
                app.SelfreactingredgasCheckBox.Visible = "off";
                app.kcalmolLabel.Visible = "off";
                app.reactiveGas = value;
            end
        end

        % Value changed function: ThresholdcollisionenergyEditField
        function ThresholdcollisionenergyEditFieldValueChanged(app, event)
            value = app.ThresholdcollisionenergyEditField.Value;
            app.criticalEnergy = value;
        end

        % Value changed function: SelfreactingbluegasCheckBox
        function SelfreactingbluegasCheckBoxValueChanged(app, event)
            value = app.SelfreactingbluegasCheckBox.Value;
            app.selfReact_A = value;
        end

        % Value changed function: SelfreactingredgasCheckBox
        function SelfreactingredgasCheckBoxValueChanged(app, event)
            value = app.SelfreactingredgasCheckBox.Value;
            app.selfReact_B = value;
        end

        % Value changed function: EnableproberegionCheckBox
        function EnableproberegionCheckBoxValueChanged(app, event)
            value = app.EnableproberegionCheckBox.Value;
            app.probeRegion = value;
        end

        % Value changed function: GasflowCheckBox
        function GasflowCheckBoxValueChanged(app, event)
            value = app.GasflowCheckBox.Value;
            if value == 1
                app.ParticlespacingfactorEditField.Visible = "on";
                app.ParticlespacingfactorEditFieldLabel.Visible = "on";
                app.RedgasparametersPanel.Visible = "off";
                app.NoofparticlesEditField.Visible = "off";
                app.NoofparticlesEditFieldLabel.Visible = "off";
                app.IncludeheattransferfrombottomwallCheckBox.Visible = "off";
                app.ReactivegasCheckBox.Visible = "off";
                app.HeatinputEditField.Visible = "off";
                app.HeatinputEditFieldLabel.Visible = "off";
                app.WLabel.Visible = "off";
                app.ThresholdcollisionenergyEditField.Visible = "off";
                app.ThresholdcollisionenergyEditFieldLabel.Visible = "off";
                app.kcalmolLabel.Visible = "off";
                app.SelfreactingbluegasCheckBox.Visible = "off";
                app.SelfreactingredgasCheckBox.Visible = "off";
                app.gasFlow = 1;
            else
                app.ParticlespacingfactorEditField.Visible = "off";
                app.ParticlespacingfactorEditFieldLabel.Visible = "off";
                app.RedgasparametersPanel.Visible = "on";
                app.NoofparticlesEditField.Visible = "on";
                app.NoofparticlesEditFieldLabel.Visible = "on";
                app.IncludeheattransferfrombottomwallCheckBox.Visible = "on";
                app.ReactivegasCheckBox.Visible = "on";
                app.HeatinputEditField.Visible = "on";
                app.HeatinputEditFieldLabel.Visible = "on";
                app.WLabel.Visible = "on";
                app.ThresholdcollisionenergyEditField.Visible = "on";
                app.ThresholdcollisionenergyEditFieldLabel.Visible = "on";
                app.kcalmolLabel.Visible = "on";
                app.SelfreactingbluegasCheckBox.Visible = "on";
                app.SelfreactingredgasCheckBox.Visible = "on";
                app.gasFlow = 0;
            end
        end

        % Value changed function: ParticlespacingfactorEditField
        function ParticlespacingfactorEditFieldValueChanged(app, event)
            value = app.ParticlespacingfactorEditField.Value;
            app.partSpace = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GasSimulatorUIFigure = uifigure('Visible', 'off');
            app.GasSimulatorUIFigure.Name = 'Gas Simulator';
            app.TabGroup = uitabgroup(app.GasSimulatorUIFigure);
            app.SimulationSetupTab = uitab(app.TabGroup);
            app.SimulationSetupTab.Title = 'Simulation Setup';
            app.GascomponentsDropDownLabel = uilabel(app.SimulationSetupTab);
            app.GascomponentsDropDownLabel.Text = 'Gas components';
            app.GascomponentsDropDown = uidropdown(app.SimulationSetupTab);
            app.GascomponentsDropDown.Items = {'Single gas', 'Two gas mixture'};
            app.GascomponentsDropDown.ValueChangedFcn = createCallbackFcn(app, @GascomponentsDropDownValueChanged, true);
            app.GascomponentsDropDown.Value = 'Single gas';
            app.BluegasparametersPanel = uipanel(app.SimulationSetupTab);
            app.BluegasparametersPanel.Title = 'Blue gas parameters';
            app.ParticlemassEditFieldLabel = uilabel(app.BluegasparametersPanel);
            app.ParticlemassEditFieldLabel.Text = 'Particle mass';
            app.ParticlemassEditField = uieditfield(app.BluegasparametersPanel, 'numeric');
            app.ParticlemassEditField.ValueChangedFcn = createCallbackFcn(app, @ParticlemassEditFieldValueChanged, true);
            app.ParticlemassEditField.Value = 16;
            app.ParticlediameterEditFieldLabel = uilabel(app.BluegasparametersPanel);
            app.ParticlediameterEditFieldLabel.Text = 'Particle diameter';
            app.ParticlediameterEditField = uieditfield(app.BluegasparametersPanel, 'numeric');
            app.ParticlediameterEditField.ValueChangedFcn = createCallbackFcn(app, @ParticlediameterEditFieldValueChanged, true);
            app.ParticlediameterEditField.Value = 3.04;
            app.NoofparticlesEditFieldLabel = uilabel(app.BluegasparametersPanel);
            app.NoofparticlesEditFieldLabel.Text = 'No. of particles';
            app.NoofparticlesEditField = uieditfield(app.BluegasparametersPanel, 'numeric');
            app.NoofparticlesEditField.ValueChangedFcn = createCallbackFcn(app, @NoofparticlesEditFieldValueChanged, true);
            app.NoofparticlesEditField.Value = 100;
            app.InitialparticlespeedEditFieldLabel = uilabel(app.BluegasparametersPanel);
            app.InitialparticlespeedEditFieldLabel.Text = 'Initial particle speed';
            app.InitialparticlespeedEditField = uieditfield(app.BluegasparametersPanel, 'numeric');
            app.InitialparticlespeedEditField.ValueChangedFcn = createCallbackFcn(app, @InitialparticlespeedEditFieldValueChanged, true);
            app.InitialparticlespeedEditField.Value = 0.0056;
            app.gmmolLabel = uilabel(app.BluegasparametersPanel);
            app.gmmolLabel.Text = 'gm / mol';
            app.Label = uilabel(app.BluegasparametersPanel);
            app.Label.Text = 'Å';
            app.fsLabel = uilabel(app.BluegasparametersPanel);
            app.fsLabel.Text = 'Å / fs';
            app.RedgasparametersPanel = uipanel(app.SimulationSetupTab);
            app.RedgasparametersPanel.Title = 'Red gas parameters';
            app.RedgasparametersPanel.Visible = 'off';
            app.ParticlemassEditField_2Label = uilabel(app.RedgasparametersPanel);
            app.ParticlemassEditField_2Label.Text = 'Particle mass';
            app.ParticlemassEditField_2 = uieditfield(app.RedgasparametersPanel, 'numeric');
            app.ParticlemassEditField_2.ValueChangedFcn = createCallbackFcn(app, @ParticlemassEditField_2ValueChanged, true);
            app.ParticlemassEditField_2.Value = 12;
            app.ParticlediameterEditField_2Label = uilabel(app.RedgasparametersPanel);
            app.ParticlediameterEditField_2Label.Text = 'Particle diameter';
            app.ParticlediameterEditField_2 = uieditfield(app.RedgasparametersPanel, 'numeric');
            app.ParticlediameterEditField_2.ValueChangedFcn = createCallbackFcn(app, @ParticlediameterEditField_2ValueChanged, true);
            app.ParticlediameterEditField_2.Value = 3.4;
            app.NoofparticlesEditField_2Label = uilabel(app.RedgasparametersPanel);
            app.NoofparticlesEditField_2Label.Text = 'No. of particles';
            app.NoofparticlesEditField_2 = uieditfield(app.RedgasparametersPanel, 'numeric');
            app.NoofparticlesEditField_2.ValueChangedFcn = createCallbackFcn(app, @NoofparticlesEditField_2ValueChanged, true);
            app.NoofparticlesEditField_2.Value = 100;
            app.InitialparticlespeedEditField_2Label = uilabel(app.RedgasparametersPanel);
            app.InitialparticlespeedEditField_2Label.Text = 'Initial particle speed';
            app.InitialparticlespeedEditField_2 = uieditfield(app.RedgasparametersPanel, 'numeric');
            app.InitialparticlespeedEditField_2.ValueChangedFcn = createCallbackFcn(app, @InitialparticlespeedEditField_2ValueChanged, true);
            app.InitialparticlespeedEditField_2.Value = 0.0056;
            app.gmmolLabel_2 = uilabel(app.RedgasparametersPanel);
            app.gmmolLabel_2.Text = 'gm / mol';
            app.Label_2 = uilabel(app.RedgasparametersPanel);
            app.Label_2.Text = 'Å';
            app.fsLabel_2 = uilabel(app.RedgasparametersPanel);
            app.fsLabel_2.Text = 'Å / fs';
            app.SimulationenvironmentsettingsPanel = uipanel(app.SimulationSetupTab);
            app.SimulationenvironmentsettingsPanel.Title = 'Simulation environment settings';
            app.IncludeinnerpartitionsCheckBox = uicheckbox(app.SimulationenvironmentsettingsPanel);
            app.IncludeinnerpartitionsCheckBox.ValueChangedFcn = createCallbackFcn(app, @IncludeinnerpartitionsCheckBoxValueChanged, true);
            app.IncludeinnerpartitionsCheckBox.Text = 'Include inner partitions';
            app.IncludeheattransferfrombottomwallCheckBox = uicheckbox(app.SimulationenvironmentsettingsPanel);
            app.IncludeheattransferfrombottomwallCheckBox.ValueChangedFcn = createCallbackFcn(app, @IncludeheattransferfrombottomwallCheckBoxValueChanged, true);
            app.IncludeheattransferfrombottomwallCheckBox.Text = 'Include heat transfer from bottom wall';
            app.BoxwidthEditFieldLabel = uilabel(app.SimulationenvironmentsettingsPanel);
            app.BoxwidthEditFieldLabel.Text = 'Box width';
            app.BoxwidthEditField = uieditfield(app.SimulationenvironmentsettingsPanel, 'numeric');
            app.BoxwidthEditField.ValueChangedFcn = createCallbackFcn(app, @BoxwidthEditFieldValueChanged, true);
            app.BoxwidthEditField.Value = 100;
            app.BoxheightEditFieldLabel = uilabel(app.SimulationenvironmentsettingsPanel);
            app.BoxheightEditFieldLabel.Text = 'Box height';
            app.BoxheightEditField = uieditfield(app.SimulationenvironmentsettingsPanel, 'numeric');
            app.BoxheightEditField.ValueChangedFcn = createCallbackFcn(app, @BoxheightEditFieldValueChanged, true);
            app.BoxheightEditField.Value = 100;
            app.TotalsimulationtimeEditFieldLabel = uilabel(app.SimulationenvironmentsettingsPanel);
            app.TotalsimulationtimeEditFieldLabel.Text = 'Total simulation time';
            app.TotalsimulationtimeEditField = uieditfield(app.SimulationenvironmentsettingsPanel, 'numeric');
            app.TotalsimulationtimeEditField.ValueChangedFcn = createCallbackFcn(app, @TotalsimulationtimeEditFieldValueChanged, true);
            app.TotalsimulationtimeEditField.Value = 100000;
            app.TimestepEditFieldLabel = uilabel(app.SimulationenvironmentsettingsPanel);
            app.TimestepEditFieldLabel.Text = 'Time step';
            app.TimestepEditField = uieditfield(app.SimulationenvironmentsettingsPanel, 'numeric');
            app.TimestepEditField.ValueChangedFcn = createCallbackFcn(app, @TimestepEditFieldValueChanged, true);
            app.TimestepEditField.Value = 100;
            app.HeatinputEditFieldLabel = uilabel(app.SimulationenvironmentsettingsPanel);
            app.HeatinputEditFieldLabel.Visible = 'off';
            app.HeatinputEditFieldLabel.Text = 'Heat input';
            app.HeatinputEditField = uieditfield(app.SimulationenvironmentsettingsPanel, 'numeric');
            app.HeatinputEditField.ValueChangedFcn = createCallbackFcn(app, @HeatinputEditFieldValueChanged, true);
            app.HeatinputEditField.Visible = 'off';
            app.HeatinputEditField.Value = 5e-08;
            app.IncludegravityCheckBox = uicheckbox(app.SimulationenvironmentsettingsPanel);
            app.IncludegravityCheckBox.ValueChangedFcn = createCallbackFcn(app, @IncludegravityCheckBoxValueChanged, true);
            app.IncludegravityCheckBox.Text = 'Include gravity';
            app.AcclelerationduetogravityEditFieldLabel = uilabel(app.SimulationenvironmentsettingsPanel);
            app.AcclelerationduetogravityEditFieldLabel.Visible = 'off';
            app.AcclelerationduetogravityEditFieldLabel.Text = 'Accleleration due to gravity';
            app.AcclelerationduetogravityEditField = uieditfield(app.SimulationenvironmentsettingsPanel, 'numeric');
            app.AcclelerationduetogravityEditField.ValueChangedFcn = createCallbackFcn(app, @AcclelerationduetogravityEditFieldValueChanged, true);
            app.AcclelerationduetogravityEditField.Visible = 'off';
            app.AcclelerationduetogravityEditField.Value = 9.8;
            app.ReactivegasCheckBox = uicheckbox(app.SimulationenvironmentsettingsPanel);
            app.ReactivegasCheckBox.ValueChangedFcn = createCallbackFcn(app, @ReactivegasCheckBoxValueChanged, true);
            app.ReactivegasCheckBox.Text = 'Reactive gas';
            app.ThresholdcollisionenergyEditFieldLabel = uilabel(app.SimulationenvironmentsettingsPanel);
            app.ThresholdcollisionenergyEditFieldLabel.Visible = 'off';
            app.ThresholdcollisionenergyEditFieldLabel.Text = 'Threshold collision energy';
            app.ThresholdcollisionenergyEditField = uieditfield(app.SimulationenvironmentsettingsPanel, 'numeric');
            app.ThresholdcollisionenergyEditField.ValueChangedFcn = createCallbackFcn(app, @ThresholdcollisionenergyEditFieldValueChanged, true);
            app.ThresholdcollisionenergyEditField.Visible = 'off';
            app.ThresholdcollisionenergyEditField.Value = 1.5;
            app.SelfreactingbluegasCheckBox = uicheckbox(app.SimulationenvironmentsettingsPanel);
            app.SelfreactingbluegasCheckBox.ValueChangedFcn = createCallbackFcn(app, @SelfreactingbluegasCheckBoxValueChanged, true);
            app.SelfreactingbluegasCheckBox.Visible = 'off';
            app.SelfreactingbluegasCheckBox.Text = 'Self-reacting blue gas';
            app.SelfreactingbluegasCheckBox.Value = true;
            app.SelfreactingredgasCheckBox = uicheckbox(app.SimulationenvironmentsettingsPanel);
            app.SelfreactingredgasCheckBox.ValueChangedFcn = createCallbackFcn(app, @SelfreactingredgasCheckBoxValueChanged, true);
            app.SelfreactingredgasCheckBox.Visible = 'off';
            app.SelfreactingredgasCheckBox.Text = 'Self-reacting red gas';
            app.SelfreactingredgasCheckBox.Value = true;
            app.Label_3 = uilabel(app.SimulationenvironmentsettingsPanel);
            app.Label_3.Text = 'Å';
            app.Label_4 = uilabel(app.SimulationenvironmentsettingsPanel);
            app.Label_4.Text = 'Å';
            app.fsLabel_3 = uilabel(app.SimulationenvironmentsettingsPanel);
            app.fsLabel_3.Text = 'fs';
            app.fsLabel_4 = uilabel(app.SimulationenvironmentsettingsPanel);
            app.fsLabel_4.Text = 'fs';
            app.WLabel = uilabel(app.SimulationenvironmentsettingsPanel);
            app.WLabel.Visible = 'off';
            app.WLabel.Text = 'W';
            app.ms2Label = uilabel(app.SimulationenvironmentsettingsPanel);
            app.ms2Label.Visible = 'off';
            app.ms2Label.Text = 'm/s2';
            app.kcalmolLabel = uilabel(app.SimulationenvironmentsettingsPanel);
            app.kcalmolLabel.Visible = 'off';
            app.kcalmolLabel.Text = 'kcal/mol';
            app.AnimationandgraphsettingsPanel = uipanel(app.SimulationSetupTab);
            app.AnimationandgraphsettingsPanel.Title = 'Animation and graph settings';
            app.CreateanimationvideoCheckBox = uicheckbox(app.AnimationandgraphsettingsPanel);
            app.CreateanimationvideoCheckBox.ValueChangedFcn = createCallbackFcn(app, @CreateanimationvideoCheckBoxValueChanged, true);
            app.CreateanimationvideoCheckBox.Text = 'Create animation video';
            app.AnimationspeedEditFieldLabel = uilabel(app.AnimationandgraphsettingsPanel);
            app.AnimationspeedEditFieldLabel.Text = 'Animation speed';
            app.AnimationspeedEditField = uieditfield(app.AnimationandgraphsettingsPanel, 'numeric');
            app.AnimationspeedEditField.ValueChangedFcn = createCallbackFcn(app, @AnimationspeedEditFieldValueChanged, true);
            app.AnimationspeedEditField.Value = 1;
            app.MovingaveragewindowEditFieldLabel = uilabel(app.AnimationandgraphsettingsPanel);
            app.MovingaveragewindowEditFieldLabel.Text = 'Moving average window';
            app.MovingaveragewindowEditField = uieditfield(app.AnimationandgraphsettingsPanel, 'numeric');
            app.MovingaveragewindowEditField.ValueChangedFcn = createCallbackFcn(app, @MovingaveragewindowEditFieldValueChanged, true);
            app.MovingaveragewindowEditField.Value = 100;
            app.EnableproberegionCheckBox = uicheckbox(app.AnimationandgraphsettingsPanel);
            app.EnableproberegionCheckBox.ValueChangedFcn = createCallbackFcn(app, @EnableproberegionCheckBoxValueChanged, true);
            app.EnableproberegionCheckBox.Text = 'Enable probe region';
            app.OpeninteractiveinputwindowButton = uibutton(app.SimulationSetupTab, 'push');
            app.OpeninteractiveinputwindowButton.ButtonPushedFcn = createCallbackFcn(app, @OpeninteractiveinputwindowButtonPushed, true);
            app.OpeninteractiveinputwindowButton.Text = 'Open interactive input window';
            app.StartsimulationButton = uibutton(app.SimulationSetupTab, 'push');
            app.StartsimulationButton.ButtonPushedFcn = createCallbackFcn(app, @StartsimulationButtonPushed, true);
            app.StartsimulationButton.Text = 'Start simulation';
            app.StatusEditFieldLabel = uilabel(app.SimulationSetupTab);
            app.StatusEditFieldLabel.Text = 'Status';
            app.StatusEditField = uieditfield(app.SimulationSetupTab, 'text');
            app.StatusEditField.Editable = 'off';
            app.GasflowCheckBox = uicheckbox(app.SimulationSetupTab);
            app.GasflowCheckBox.ValueChangedFcn = createCallbackFcn(app, @GasflowCheckBoxValueChanged, true);
            app.GasflowCheckBox.Text = 'Gas flow';
            app.ParticlespacingfactorEditFieldLabel = uilabel(app.SimulationSetupTab);
            app.ParticlespacingfactorEditFieldLabel.Visible = 'off';
            app.ParticlespacingfactorEditFieldLabel.Text = 'Particle spacing factor';
            app.ParticlespacingfactorEditField = uieditfield(app.SimulationSetupTab, 'numeric');
            app.ParticlespacingfactorEditField.ValueChangedFcn = createCallbackFcn(app, @ParticlespacingfactorEditFieldValueChanged, true);
            app.ParticlespacingfactorEditField.Visible = 'off';
            app.ParticlespacingfactorEditField.Value = 2;
            app.PlotsofThermophysicalQuantitiesTab = uitab(app.TabGroup);
            app.PlotsofThermophysicalQuantitiesTab.Title = 'Plots of Thermo-physical Quantities';
            app.UIAxes = uiaxes(app.PlotsofThermophysicalQuantitiesTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.SelectagraphButtonGroup = uibuttongroup(app.PlotsofThermophysicalQuantitiesTab);
            app.SelectagraphButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SelectagraphButtonGroupSelectionChanged, true);
            app.SelectagraphButtonGroup.Title = 'Select a graph';
            app.TopwallpressureButton = uiradiobutton(app.SelectagraphButtonGroup);
            app.TopwallpressureButton.Text = 'Top wall pressure';
            app.TopwallpressureButton.Value = true;
            app.SpeeddistributionButton = uiradiobutton(app.SelectagraphButtonGroup);
            app.SpeeddistributionButton.Text = 'Speed distribution';
            app.RegioninternalenergyButton = uiradiobutton(app.SelectagraphButtonGroup);
            app.RegioninternalenergyButton.Text = 'Region internal energy';
            app.RegionconcentrationButton = uiradiobutton(app.SelectagraphButtonGroup);
            app.RegionconcentrationButton.Text = 'Region concentration';
            app.PlotofChemicalSpeciesTab = uitab(app.TabGroup);
            app.PlotofChemicalSpeciesTab.Title = 'Plot of Chemical Species';
            app.UIAxes2 = uiaxes(app.PlotofChemicalSpeciesTab);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.HelpTab = uitab(app.TabGroup);
            app.HelpTab.Title = 'Help';
            app.HowtouseTextAreaLabel = uilabel(app.HelpTab);
            app.HowtouseTextAreaLabel.Text = 'How to use';
            app.HowtouseTextArea = uitextarea(app.HelpTab);
            app.HowtouseTextArea.Editable = 'off';
            app.HowtouseTextArea.Value = {'1. In "Simulation Setup" tab, select no. of gas species from the drop down'; ''; '2. Select "Gas flow" to simulate flow of blue gas. Gas will flow from left to right side of the box. "Initial particle '; '    speed" in this case represents inlet flow velocity. '; ''; '2. Input the gas parameter values'; ''; '3. Select and specify the simulation environement, animation and graph settings'; ''; '4. Click on "Open interactive input window" to specify initial conditions interactively using mouse'; ''; '5. Press Enter after specifying initial location regions for all the gas species and the probe region'; ''; '6. Press Enter after drawing a single wall to continue drawing another wall. Press "x" to terminate drawing walls.'; ''; '7. Click on "Start simulation" to begin solving '; ''; '8. Select "Graphs" and "Thermo-physical Quantities" tabs to view output results'};
            app.MiscelleneousInfoTextAreaLabel = uilabel(app.HelpTab);
            app.MiscelleneousInfoTextAreaLabel.Text = 'Miscelleneous Info';
            app.MiscelleneousInfoTextArea = uitextarea(app.HelpTab);
            app.MiscelleneousInfoTextArea.Editable = 'off';
            app.MiscelleneousInfoTextArea.Value = {'>> The default gas parameter values correspond to Oxygen (blue) and Carbon (red) atoms at 298 K temperature '; '     and 10^18 atoms/m2'; ''; '>> Particle inter-space = (Particle spacing factor)*size'; ''; '>> Particle spacing factor should be >= 2 and animation speed should >=1 and an integer'; ''; '>> After toggling "Gas flow" check box, toggle other check boxes on/off while using them to correctly set the '; '     view state of numeric fields. '};
            % Show the figure after all components are created
            app.GasSimulatorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Gas_sim

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GasSimulatorUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GasSimulatorUIFigure)
        end
    end
end